classdef USGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CreatecystphantomimageButton   matlab.ui.control.Button
        SamplingfrequencyinterpolationfactorEditField  matlab.ui.control.NumericEditField
        SamplingfrequencyinterpolationfactorEditFieldLabel  matlab.ui.control.Label
        SamplingfrequencydecimationfactorEditField  matlab.ui.control.NumericEditField
        SamplingfrequencydecimationfactorEditFieldLabel  matlab.ui.control.Label
        TimetostartcollectingsampleEditField  matlab.ui.control.NumericEditField
        TimetostartcollectingsampleEditFieldLabel  matlab.ui.control.Label
        SizeofimagesectorEditField     matlab.ui.control.NumericEditField
        SizeofimagesectorEditFieldLabel  matlab.ui.control.Label
        NumberoflinesinimageEditField  matlab.ui.control.NumericEditField
        NumberoflinesinimageEditFieldLabel  matlab.ui.control.Label
        SpeedofsoundmsEditField        matlab.ui.control.NumericEditField
        SpeedofsoundmsEditFieldLabel   matlab.ui.control.Label
        SamplingfrequencyHzEditField   matlab.ui.control.NumericEditField
        SamplingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        TransducercenterfrequencyHzEditField  matlab.ui.control.NumericEditField
        TransducercenterfrequencyHzEditFieldLabel  matlab.ui.control.Label
        InitializeFieldIIButton        matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeFieldIIButton
        function InitializeFieldIIButtonPushed(app, event)
            %  Procedure for initializing the Field II program system. Must be 
            %  the first routine that is called before using the system.
            %
            %  Calling:  field_init (suppress);
            %
            %  Return:   nothing.
            %
            %  Input:  suppress: An optional argument suppress with a value 
            %                    of zero can be given to suppress the
            %                    display of the initial field screen.
            %                    No ACII ouput will be given, if the argument
            %                    is -1. Debug messages will be written if
            %		     enable by field_debug, and all error messages
            %		     will also be printed.
            %
            %  Version 1.2, January 20, 1999 by Joergen Arendt Jensen
            
            function res = field_init (suppress)
            
            %  Call the C-part of the program to initialize it
            
              if (nargin==1)
                Mat_field (5001,suppress);
              else
                Mat_field (5001,1);
              end
            end
        end

        % Button pushed function: CreatecystphantomimageButton
        function CreatecystphantomimageButtonPushed(app, event)
            %  Compress the data to show 60 dB of
            %  dynamic range for the cyst phantom image
            %
            %  version 1.3 by Joergen Arendt Jensen, April 1, 1998.
            %  version 1.4 by Joergen Arendt Jensen, August 13, 2007.
            %          Clibrated 60 dB display made
             
            f0=app.TransducercenterfrequencyHzEditField.Value;                 %  Transducer center frequency [Hz]
            fs=app.SamplingfrequencyHzEditField.Value;                 %  Sampling frequency [Hz]
            c=app.SpeedofsoundmsEditField.Value;                   %  Speed of sound [m/s]
            no_lines=app.NumberoflinesinimageEditField.Value;              %  Number of lines in image
            image_width=app.SizeofimagesectorEditField.Value;      %  Size of image sector
            d_x=image_width/no_lines; %  Increment for image
            t_start=app.TimetostartcollectingsampleEditField.Value;         %Time to start collecting sample
            
            %%  Read the data and adjust it in time 
            
            min_sample=0;
            for i=1:no_lines
                
                %  Load the result
                
                cmd=['load rf_ln',num2str(i),'.mat'];
                disp(cmd)
                eval(cmd)
                
                %  Find the envelope
                
                rf_env=abs(hilbert([zeros(round(t_start*fs-min_sample),1); rf_data]));
                env(1:max(size(rf_env)),i)=rf_env;
            end
            
            %  Do logarithmic compression
            
            D=app.SamplingfrequencydecimationfactorEditField.Value;   %  Sampling frequency decimation factor
            
            disp('Finding the envelope')
            log_env=env(1:D:max(size(env)),:)/max(max(env));
            log_env=20*log10(log_env);
            log_env=127/60*(log_env+60);
            
            %  Make an interpolated image
            
            disp('Doing interpolation')
            ID=app.SamplingfrequencyinterpolationfactorEditField.Value;
            [n,m]=size(log_env);
            new_env=zeros(n,m*ID);
            for i=1:n
              new_env(i,:)=abs(interp(log_env(i,:),ID));
             end
            [n,m]=size(new_env);
              
            fn=fs/D;
            image(app.UIAxes,((1:(ID*no_lines-1))*d_x/ID-no_lines*d_x/2)*1000,((1:n)/fn+min_sample/fs)*1540/2*1000,new_env)
            %image(new_env)
            title(app.UIAxes, 'Cyst phantom image')
            xlabel(app.UIAxes, 'Lateral distance [mm]')
            ylabel(app.UIAxes, 'Axial distance [mm]')
            colormap(app.UIAxes,gray(127))
            axis(app.UIAxes,'image')
            axis(app.UIAxes,[-20 20 30 90])

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InitializeFieldIIButton = uibutton(app.UIFigure, 'push');
            app.InitializeFieldIIButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeFieldIIButtonPushed, true);
            app.InitializeFieldIIButton.Text = 'Initialize Field II';
            app.TransducercenterfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.TransducercenterfrequencyHzEditFieldLabel.Text = 'Transducer center frequency [Hz]';
            app.TransducercenterfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfrequencyHzEditFieldLabel.Text = 'Sampling frequency [Hz]';
            app.SamplingfrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedofsoundmsEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedofsoundmsEditFieldLabel.Text = 'Speed of sound [m/s]';
            app.SpeedofsoundmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoflinesinimageEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoflinesinimageEditFieldLabel.Text = 'Number of lines in image';
            app.NumberoflinesinimageEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeofimagesectorEditFieldLabel = uilabel(app.UIFigure);
            app.SizeofimagesectorEditFieldLabel.Text = 'Size of image sector';
            app.SizeofimagesectorEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimetostartcollectingsampleEditFieldLabel = uilabel(app.UIFigure);
            app.TimetostartcollectingsampleEditFieldLabel.Text = 'Time to start collecting sample';
            app.TimetostartcollectingsampleEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingfrequencydecimationfactorEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfrequencydecimationfactorEditFieldLabel.Text = 'Sampling frequency decimation factor';
            app.SamplingfrequencydecimationfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingfrequencyinterpolationfactorEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfrequencyinterpolationfactorEditFieldLabel.Text = 'Sampling frequency interpolation factor';
            app.SamplingfrequencyinterpolationfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CreatecystphantomimageButton = uibutton(app.UIFigure, 'push');
            app.CreatecystphantomimageButton.ButtonPushedFcn = createCallbackFcn(app, @CreatecystphantomimageButtonPushed, true);
            app.CreatecystphantomimageButton.Text = 'Create cyst phantom image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = USGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end